classdef ProyectoApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UnidaddeEfectosdeAudioUIFigure  matlab.ui.Figure
        Efectos1Panel                   matlab.ui.container.Panel
        EcoCheckBox1                    matlab.ui.control.CheckBox
        TremoloCheckBox1                matlab.ui.control.CheckBox
        FlangerCheckBox1                matlab.ui.control.CheckBox
        DistCheckBox1                   matlab.ui.control.CheckBox
        VibratoCheckBox1                matlab.ui.control.CheckBox
        AplicarEfecto1                  matlab.ui.control.Button
        ReproducirEfecto1               matlab.ui.control.Button
        LampEfecto1                     matlab.ui.control.Lamp
        SLabel                          matlab.ui.control.Label
        SliderFlanger1                  matlab.ui.control.Slider
        SliderVibratoFreq1              matlab.ui.control.Slider
        HzLabel_6                       matlab.ui.control.Label
        HzLabel_7                       matlab.ui.control.Label
        SliderVibratoMS1                matlab.ui.control.Slider
        msLabel                         matlab.ui.control.Label
        DelayEditFieldLabel             matlab.ui.control.Label
        EcoDelayEditField1              matlab.ui.control.NumericEditField
        AlfaEditFieldLabel              matlab.ui.control.Label
        EcoAlfaEditField1               matlab.ui.control.NumericEditField
        HzLabel_9                       matlab.ui.control.Label
        HzLabel_10                      matlab.ui.control.Label
        FreqLabel                       matlab.ui.control.Label
        DistValueEditField1             matlab.ui.control.NumericEditField
        DropDown                        matlab.ui.control.DropDown
        ArchivoEntradaPanel             matlab.ui.container.Panel
        ExaminarButton_2                matlab.ui.control.Button
        ReproducirarchivoButton         matlab.ui.control.Button
        ArchivoAudioLabel               matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        DuracionLabel                   matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        DurationLabel                   matlab.ui.control.Label
        CanalesLabel                    matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        NumCanalesLabel                 matlab.ui.control.Label
        Banda1Panel                     matlab.ui.container.Panel
        FreqaltaEditFieldLabel          matlab.ui.control.Label
        FreqaltaEditField               matlab.ui.control.NumericEditField
        FreqbajaEditFieldLabel          matlab.ui.control.Label
        FreqbajaEditField               matlab.ui.control.NumericEditField
        HzLabel                         matlab.ui.control.Label
        HzLabel_2                       matlab.ui.control.Label
        AplicarBanda1                   matlab.ui.control.Button
        LampBanda1                      matlab.ui.control.Lamp
        GananciaPanel                   matlab.ui.container.Panel
        GananciadefiltrosSlider         matlab.ui.control.Slider
        AplicarGanancia                 matlab.ui.control.Button
        ReproducirGanancia              matlab.ui.control.Button
        LampGanancia                    matlab.ui.control.Lamp
        Banda2Panel                     matlab.ui.container.Panel
        FreqaltaEditField_2Label        matlab.ui.control.Label
        FreqaltaEditField_2             matlab.ui.control.NumericEditField
        FreqbajaEditField_2Label        matlab.ui.control.Label
        FreqbajaEditField_2             matlab.ui.control.NumericEditField
        HzLabel_3                       matlab.ui.control.Label
        HzLabel_4                       matlab.ui.control.Label
        AplicarBanda2                   matlab.ui.control.Button
        LampBanda2                      matlab.ui.control.Lamp
        EspectroArchivoEntradaPanel     matlab.ui.container.Panel
        EspectroEntradaAxes             matlab.ui.control.UIAxes
        EspectroEfectoPanel             matlab.ui.container.Panel
        EspectroEfectoAxes              matlab.ui.control.UIAxes
        EspectroArchivoSalidaPanel      matlab.ui.container.Panel
        EspectroSalidaAxes              matlab.ui.control.UIAxes
        ArchivosSalidaPanel             matlab.ui.container.Panel
        GuardarButton                   matlab.ui.control.Button
        ArchivoAudioLabel_2             matlab.ui.control.Label
        GenerarmatButton                matlab.ui.control.Button
        Efectos2Panel                   matlab.ui.container.Panel
        EcoCheckBox2                    matlab.ui.control.CheckBox
        TremoloCheckBox2                matlab.ui.control.CheckBox
        FlangerCheckBox2                matlab.ui.control.CheckBox
        DistCheckBox2                   matlab.ui.control.CheckBox
        VibratoCheckBox2                matlab.ui.control.CheckBox
        AplicarEfecto2                  matlab.ui.control.Button
        ReproducirEfecto2               matlab.ui.control.Button
        LampEfecto2                     matlab.ui.control.Lamp
        SLabel_2                        matlab.ui.control.Label
        SliderFlanger2                  matlab.ui.control.Slider
        SliderVibratoFreq2              matlab.ui.control.Slider
        HzLabel_12                      matlab.ui.control.Label
        HzLabel_13                      matlab.ui.control.Label
        SliderVibratoMS2                matlab.ui.control.Slider
        msLabel_2                       matlab.ui.control.Label
        DelayEditFieldLabel_2           matlab.ui.control.Label
        EcoDelayEditField2              matlab.ui.control.NumericEditField
        AlfaEditFieldLabel_2            matlab.ui.control.Label
        EcoAlfaEditField2               matlab.ui.control.NumericEditField
        HzLabel_14                      matlab.ui.control.Label
        HzLabel_15                      matlab.ui.control.Label
        FreqLabel_2                     matlab.ui.control.Label
        DistValueEditField2             matlab.ui.control.NumericEditField
        DropDown_2                      matlab.ui.control.DropDown
    end

    
    methods (Access = private)
        
        function plotSpectrum(app, axes, data, Fs)
            
            N = Fs;
            
            XT = abs(fft(data, N)/N);
            
            XP = XT(1:N/2+1);
            XP(2:end-1) = 2*XP(2:end-1);
            
            f = Fs*(0:(N/2))/N;
            f =f';
            
            if (length(f) >= 20000)
                plot(axes, f(1:20000), XP(1:20000));
            else
                plot(axes, f, XP);
            end
            
        end
        
        function y = bandPass(app, x, Fl, Fh, Fs)
            N = 201;
            n = -floor(N/2):floor(N/2);
            w = hamming(N);
            
            fcl = Fh/Fs;
            h_low = (2*fcl*sinc(2*fcl*n))' .* w;
            
            fch = Fl/Fs;
            h_high = (sinc(n) - 2*fch*sinc(n.*2*fch))' .* w;
            
            y_low = filter(h_low, [1], x);
            y_band = filter(h_high, [1], y_low);
            y = y_band;
        end
        
        function y = tremolo(app, x, conVal)
            global i1;
            global i2;
            global i3;
            global i4;
            global i5;
            global i6;
            switch conVal
                case 'Catedral'
                    h = i1;
                case 'Iglesia'
                    h = i2;
                case 'Envase'
                    h = i3;
                case 'Piramide'
                    h = i4;
                case 'Habitacion'
                    h = i5;
                case 'Sanitario'
                    h = i6;
                otherwise
                    h = i1;
            end
            Ly=length(x)+length(h)-1;
            Ly2=pow2(nextpow2(Ly)); % Potencia más pequeña de y
            X = zeros(Ly2, 1);
            H = zeros(Ly2, 1);
            y = zeros(Ly, 1);
            X=fft(x, Ly2);
            H=fft(h, Ly2);
            Y=X.*H;
            y=real(ifft(Y, Ly2));      % invirtiendo FFt
            y=y(1:1:length(x));               % Tomar los primeros N elementos
            y=y/max(abs(y));           % Normalizar
        end
        
        function y = flanger(app, x, val, Fs)
            maxDelay = 0.003; % Definiendo Retardo máximo
            amp = 0.7; % Coeficiente recomendado para el efecto
            rate = val; % oscilacion,  Hz
            
            % crear señales de control
            index = 1:length(x);
            sigRef = (sin(2*pi*index*(rate/Fs)))';
            maxSampDelay = round(maxDelay*Fs); % obteniendo delay máximo en muestras
            y = zeros(length(x),1); % creando vectores
            y(1:maxSampDelay) = x(1:maxSampDelay); % Para evitar muestrear datos innecesarios.
            
            % Procesando Audio
            for i = (maxSampDelay+1):length(x)
                actualSig=abs(sigRef(i));    % dato actual
                actualDelay=ceil(actualSig*maxSampDelay);  % Generando el nuevo delay
                y(i) = (amp*x(i)) + amp*(x(i-actualDelay));   % Añadir dato con delay
            end
            
            % Normalizando
            if (abs(max(y)) > abs(min(y)))
                y = y./abs(max(y));
            else
                y = y./abs(min(y));
            end
        end
        
        
        function y = vibrato(app, x, Freq, ms, Fs)
            Modfreq = Freq; %en Khz
            Width = ms / 1000; % en Segundos (0.8 milisegundos)
            ya_alt=0;
            Delay=round(Width*Fs); % Delay base, per o en muestras
            Width_P=round(Width*Fs); % modulacion del pulso, en muestras
            
            Modfreq_p=Modfreq/Fs; % Frecuencia de modulacion, en muestras
            Len=length(x);
            L=2+Delay+Width_P*2; % Ancho del delay
            
            % Crear vectores contenedores
            Delayline=zeros(L,1);
            y=zeros(size(x));
            
            % Creando Efecto
            for n=1:(Len-1)
                M = Modfreq_p;
                alter = sin(M*2*pi*n);
                modulador = 1+Delay+Width_P*alter;
                i=floor(modulador);
                frac=modulador-i;
                Delayline=[x(n);Delayline(1:L-1)];
                
                y(n,1)=Delayline(i+1)*frac+Delayline(i)*(1-frac);
            end
        end
        
        function y = ring(app, x, Fc, Fs)
            index = 1:length(x);
            % Frecuencia de oscilacion
            carrier = sin(2*pi*index*(Fc/Fs))';
            y = x.*carrier;
        end
        
        function y = eco(app, x, alfa, delay, Fs)
            
            y = zeros(length(x), 1);
            y_n = 0;
            
            for n = 1:length(x)
                y(n) = x(n) + alfa*y_n;
                
                if (n > delay*Fs)
                    y_n = y(n-floor(delay*Fs));
                end
            end
        end
        
        function efectos1Enable(app, enable)
            app.EcoCheckBox1.Enable = enable;
            %app.TremoloCheckBox1.Enable = enable; % bugged for some reason
            app.FlangerCheckBox1.Enable = enable;
            
        end
        
        
        function plotBandPass(app, axis, Fs)
            global band1HF;
            global band1LF;
            global band2HF;
            global band2LF;
            global band1Data;
            global band2Data;
            
            N = 201;
            n = -floor(N/2):floor(N/2);
            w = hamming(N);
            
            fcl_1 = band1HF/Fs;
            h_low_1 = (2*fcl_1*sinc(2*fcl_1*n))' .* w;
            
            fch_1 = band1LF/Fs;
            h_high_1 = (sinc(n) - 2*fch_1*sinc(n.*2*fch_1))' .* w;
            
            [Hlow_1, w1] = freqz(h_low_1, [1]);
            [Hhigh_1, w1] = freqz(h_high_1, [1]);
            highFreq_1 = 0;
            freq1 = w1*Fs/(2*pi);
            
            func1 = abs(Hlow_1.*Hhigh_1);
            
            for i = 1:length(freq1)
                if freq1(i) > 20000
                    highFreq_1 = i;
                    break;
                else
                    highFreq_1 = length(freq1);
                end
                
            end
            %------------------------------------------
            
            fcl_2 = band2HF/Fs;
            h_low_2 = (2*fcl_2*sinc(2*fcl_2*n))' .* w;
            
            fch_2 = band2LF/Fs;
            h_high_2 = (sinc(n) - 2*fch_2*sinc(n.*2*fch_2))' .* w;
            
            [Hlow_2, w2] = freqz(h_low_2, [1]);
            [Hhigh_2, w2] = freqz(h_high_2, [1]);
            highFreq_2 = 0;
            freq2 = w2*Fs/(2*pi);
            
            func2 = abs(Hlow_2.*Hhigh_2);
            
            for i = 1:length(freq2)
                if freq2(i) > 20000
                    highFreq_2 = i;
                    break;
                else
                    highFreq_2 = length(freq2);
                end
            end
            %------------------------------------------
            
            plot(axis, freq1(1:highFreq_1), func1(1:highFreq_1));
            hold(axis, "on");
            plot(axis, freq1(1:highFreq_2), func2(1:highFreq_2));
            hold(axis, "off");
            if length(freq1) > 20000
                xlim(axis, [0 20000])
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear;
            global band1HF;
            global band1LF;
            global band2HF;
            global band2LF;
            global ganancia;
            
            global ecoFlag1;
            global ecoAlfa1;
            global ecoDelay1;
            global tremoloFlag1;
            global tremoloValue1;
            global flangerFlag1;
            global flangerValue1;
            global vibratoFlag1;
            global vibratoFreqValue1;
            global vibratoMSValue1;
            global distFlag1;
            global distValue1;
            
            global ecoFlag2;
            global ecoAlfa2;
            global ecoDelay2;
            global tremoloFlag2;
            global tremoloValue2;
            global flangerFlag2;
            global flangerValue2;
            global vibratoFlag2;
            global vibratoFreqValue2;
            global vibratoMSValue2;
            global distFlag2;
            global distValue2;
            
            global effect1Active;
            global effect2Active;
            global filter1;
            global filter2;
            
            global i1;
            global Fs1;
            global i2;
            global Fs2;
            global i3;
            global Fs3;
            global i4;
            global Fs4;
            global i5;
            global Fs5;
            global i6;
            global Fs6;
            global convData;
            global convData2;
            
            ecoFlag1 = 0;
            tremoloFlag1 = 0;
            flangerFlag1 = 0;
            vibratoFlag1 = 0;
            distFlag1 = 0;
            tremoloValue1 = 0.5;
            flangerValue1 = 0.5;
            ecoAlfa1 = 0;
            ecoDelay1 = 0;
            vibratoFreqValue1 = 1;
            vibratoMSValue1 = 0.5;
            distValue1 = 20;
            
            ecoFlag2 = 0;
            tremoloFlag2 = 0;
            flangerFlag2 = 0;
            vibratoFlag2 = 0;
            distFlag2 = 0;
            tremoloValue2 = 0.5;
            flangerValue2 = 0.5;
            ecoAlfa2 = 0;
            ecoDelay2 = 0;
            vibratoFreqValue2 = 1;
            vibratoMSValue2 = 0.5;
            distValue2 = 20;
            
            effect1Active = false;
            effect2Active = false;
            
            band1HF = 20000;
            band1LF = 0;
            band2HF = 20000;
            band2LF = 0;
            
            filter1 = false;
            filter2 = false;
            
            [i1,Fs1] = audioread('i_cathedral.wav');
            [i2,Fs2] = audioread('i_church.wav');
            [i3,Fs3] = audioread('i_pringles.wav');
            [i4,Fs4] = audioread('i_pyramid.wav');
            [i5,Fs5] = audioread('i_room.wav');
            [i6,Fs6] = audioread('i_wc.wav');
            
            convData = i1;
            convData2 = i1;
            
            ganancia = 0;
        end

        % Button pushed function: ExaminarButton_2
        function ExaminarButton_2Pushed(app, event)
            global inputPath;
            global inputFile;
            global fileSelected;
            global songData;
            global finalData;
            global Fs;
            global channels;
            global band1LF;
            global band1HF;
            
            fileSelected = 0;
            
            [inputFile, inputPath] = uigetfile(".wav");
            if isequal(inputFile,0)
                % not file
                app.ArchivoAudioLabel.Text = "<Archivo Audio>";
                app.ArchivoAudioLabel_2.Text = "<Archivo Audio>";
                fileSelected = 0;
            else
                % file selected
                app.ArchivoAudioLabel.Text = inputFile;
                
                fileSelected = 1;
                
                % load audio data
                [songData, Fs] = audioread(join([inputPath inputFile], ""));
                
                % do not remove, solves rare bug
                finalData = zeros(size(songData));
                
                % cantidad de canales
                channels = size(songData,2);
                app.NumCanalesLabel.Text = string(channels);
                
                % mostrar duracion
                duration = size(songData, 1)/Fs;
                min = floor(duration/60);
                secs = duration - min*60;
                app.DurationLabel.Text = sprintf("%02.0f:%05.2f", min, secs);
                
                % graficar espectro entrada
                app.plotSpectrum(app.EspectroEntradaAxes, songData, Fs);
                
                % Habilitar Aplicacion
                app.ReproducirarchivoButton.Enable = true;
                app.AplicarBanda1.Enable = true;
                app.AplicarBanda2.Enable = true;
                
                % Deshabilitar otros botones
                app.AplicarEfecto1.Enable = false;
                app.ReproducirEfecto1.Enable = false;
                app.AplicarEfecto2.Enable = false;
                app.ReproducirEfecto2.Enable = false;
                app.AplicarGanancia.Enable = false;
                app.ReproducirGanancia.Enable = false;
                app.GuardarButton.Enable = false;
                app.GenerarmatButton.Enable = false;
                app.LampBanda1.Color = [0.64 0.08 0.18];
                app.LampEfecto1.Color = [0.64 0.08 0.18];
                app.LampBanda2.Color = [0.64 0.08 0.18];
                app.LampEfecto2.Color = [0.64 0.08 0.18];
                app.LampGanancia.Color = [0.64 0.08 0.18];
            end
        end

        % Button pushed function: ReproducirarchivoButton
        function ReproducirarchivoButtonPushed(app, event)
            global songData;
            global Fs;
            global fileSelected;
            if isequal(fileSelected, 1)
                soundsc(songData, Fs);
            end
        end

        % Value changing function: GananciadefiltrosSlider
        function GananciadefiltrosSliderValueChanging(app, event)
            global gainValue;
            gainValue = event.Value;
        end

        % Value changed function: FreqaltaEditField
        function FreqaltaEditFieldValueChanged(app, event)
            global band1HF;
            global filter1;
            filter1 = false;
            band1HF = app.FreqaltaEditField.Value;
            app.LampBanda1.Color = [0.64 0.08 0.18];
            app.LampEfecto1.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
            
        end

        % Value changed function: FreqbajaEditField
        function FreqbajaEditFieldValueChanged(app, event)
            global band1LF;
            global filter1;
            filter1 = false;
            band1LF = app.FreqbajaEditField.Value;
            app.LampBanda1.Color = [0.64 0.08 0.18];
            app.LampEfecto1.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
            
        end

        % Value changed function: FreqaltaEditField_2
        function FreqaltaEditField_2ValueChanged(app, event)
            global band2HF;
            global filter2;
            filter2 = false;
            band2HF = app.FreqaltaEditField_2.Value;
            app.LampBanda2.Color = [0.64 0.08 0.18];
            app.LampEfecto2.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
            
        end

        % Value changed function: FreqbajaEditField_2
        function FreqbajaEditField_2ValueChanged(app, event)
            global band2LF;
            global filter2;
            filter2 = false;
            band2LF = app.FreqbajaEditField_2.Value;
            app.LampBanda2.Color = [0.64 0.08 0.18];
            app.LampEfecto2.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
            
        end

        % Button pushed function: AplicarBanda1
        function AplicarBanda1ButtonPushed(app, event)
            global songData;
            global band1LF;
            global band1HF;
            global band2LF;
            global band2HF;
            global Fs;
            global band1Data;
            global channels;
            global filter1;
            global filter2;
            
            band1Data = zeros(size(songData));
            
            if (channels == 2)
                band1Data(:,1) = app.bandPass(songData(:,1), band1LF, band1HF, Fs);
                band1Data(:,2) = app.bandPass(songData(:,2), band1LF, band1HF, Fs);
            else
                band1Data = app.bandPass(songData, band1LF, band1HF, Fs);
            end
            app.LampBanda1.Color = [0.47 0.67 0.19];
            
            %app.efectos1Enable(true);
            
            % for debug
            %app.plotSpectrum(app.EspectroSalidaAxes, band1Data, Fs);
            
            % Habilitando Efectos1
            app.AplicarEfecto1.Enable = true;
            app.ReproducirEfecto1.Enable = true;
            %app.plotBandPass(app.EspectroEfectoAxes, songData, band1LF, band1HF, Fs);
            % Graficando banda
            filter1 = true;
            if (filter1 & filter2)
                app.plotBandPass(app.EspectroEfectoAxes, Fs)
            end
        end

        % Button pushed function: AplicarBanda2
        function AplicarBanda2ButtonPushed(app, event)
            global songData;
            global band2LF;
            global band2HF;
            global Fs;
            global band2Data;
            global channels;
            global filter1;
            global filter2;
            
            band2Data = zeros(size(songData));
            
            if (channels == 2)
                band2Data(:,1) = app.bandPass(songData(:,1), band2LF, band2HF, Fs);
                band2Data(:,2) = app.bandPass(songData(:,2), band2LF, band2HF, Fs);
            else
                band2Data = app.bandPass(songData, band2LF, band2HF, Fs);
            end
            app.LampBanda2.Color = [0.47 0.67 0.19];
            
            % for debug
            %app.plotSpectrum(app.EspectroSalidaAxes, band2Data, Fs);
            
            % Habilitando Efectos1
            app.AplicarEfecto2.Enable = true;
            app.ReproducirEfecto2.Enable = true;
            
            % Graficando banda
            filter2 = true;
            if (filter1 & filter2)
                app.plotBandPass(app.EspectroEfectoAxes, Fs);
            end
        end

        % Value changed function: TremoloCheckBox1
        function TremoloCheckBox1ValueChanged(app, event)
            global tremoloFlag1;
            tremoloFlag1 = app.TremoloCheckBox1.Value;
            app.LampEfecto1.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
        end

        % Button pushed function: AplicarEfecto1
        function AplicarEfecto1ButtonPushed(app, event)
            global band1Data;
            global Fs;
            global effectSong1;
            global ecoFlag1;
            global ecoAlfa1;
            global ecoDelay1;
            global tremoloFlag1;
            global tremoloValue1;
            global flangerFlag1;
            global flangerValue1;
            global vibratoFlag1;
            global vibratoFreqValue1;
            global vibratoMSValue1;
            global distFlag1;
            global distValue1;
            global channels;
            global convData;
            
            global effect1Active;
            global effect2Active;
            
            %4186 Hz Frecuencia mas alta de un piano
            
            effectSong1 = band1Data;
            
            % Aplicar Eco
            if (ecoFlag1 == 1)
                if (channels == 2)
                    effectSong1(:,1) = app.eco(effectSong1(:,1), ecoAlfa1, ecoDelay1, Fs);
                    effectSong1(:,2) = app.eco(effectSong1(:,2), ecoAlfa1, ecoDelay1, Fs);
                else
                    effectSong1 = app.eco(effectSong1, ecoAlfa1, ecoDelay1, Fs);
                end
            end
            
            % Aplicar tremolo
            if (tremoloFlag1 == 1)
                if (channels == 2)
                    effectSong1(:,1) = app.tremolo(effectSong1(:,1), convData);
                    effectSong1(:,2) = app.tremolo(effectSong1(:,2), convData);
                else
                    effectSong1 = app.tremolo(effectSong1, convData);
                end
            end
            
            % aplicar Flanger
            if (flangerFlag1 == 1)
                if (channels == 2)
                    effectSong1(:,1) = app.flanger(effectSong1(:,1), flangerValue1, Fs);
                    effectSong1(:,2) = app.flanger(effectSong1(:,2), flangerValue1, Fs);
                else
                    effectSong1 = app.flanger(effectSong1, flangerValue1, Fs);
                end
            end
            
            % aplicar vibrato
            if (vibratoFlag1 == 1)
                if (channels == 2)
                    effectSong1(:,1) = app.vibrato(effectSong1(:,1), vibratoFreqValue1, vibratoMSValue1, Fs);
                    effectSong1(:,2) = app.vibrato(effectSong1(:,2), vibratoFreqValue1, vibratoMSValue1, Fs);
                else
                    effectSong1 = app.vibrato(effectSong1, vibratoFreqValue1, vibratoMSValue1, Fs);
                end
            end
            
            % aplicar Efecto distorsion
            if (distFlag1 == 1)
                if (channels == 2)
                    effectSong1(:,1) = app.ring(effectSong1(:,1), distValue1, Fs);
                    effectSong1(:,2) = app.ring(effectSong1(:,2), distValue1, Fs);
                else
                    effectSong1 = app.ring(effectSong1, distValue1, Fs);
                end
            end
            app.LampEfecto1.Color = [0.47 0.67 0.19];
            
            effect1Active = true;
            
            if (effect2Active & effect1Active)
                app.AplicarGanancia.Enable = true;
                app.ReproducirGanancia.Enable = true;
            end
        end

        % Button pushed function: ReproducirEfecto1
        function ReproducirEfecto1ButtonPushed(app, event)
            global effectSong1;
            global Fs;
            global fileSelected;
            if isequal(fileSelected, 1)
                soundsc(effectSong1, Fs);
            end
        end

        % Callback function
        function SliderTremolo1ValueChanged(app, event)
            global tremoloValue1;
            tremoloValue1 = app.SliderTremolo1.Value;
            app.LampEfecto1.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
        end

        % Value changed function: FlangerCheckBox1
        function FlangerCheckBox1ValueChanged(app, event)
            global flangerFlag1;
            flangerFlag1 = app.FlangerCheckBox1.Value;
            app.LampEfecto1.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
        end

        % Value changed function: SliderFlanger1
        function SliderFlanger1ValueChanged(app, event)
            global flangerValue1;
            flangerValue1 = app.SliderFlanger1.Value;
            app.LampEfecto1.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
        end

        % Value changed function: VibratoCheckBox1
        function VibratoCheckBox1ValueChanged(app, event)
            global vibratoFlag1;
            vibratoFlag1 = app.VibratoCheckBox1.Value;
            app.LampEfecto1.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
        end

        % Value changed function: SliderVibratoFreq1
        function SliderVibratoFreq1ValueChanged(app, event)
            global vibratoFreqValue1;
            vibratoFreqValue1 = app.SliderVibratoFreq1.Value;
            app.LampEfecto1.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
        end

        % Value changed function: SliderVibratoMS1
        function SliderVibratoMS1ValueChanged(app, event)
            global vibratoMSValue1;
            vibratoMSValue1= app.SliderVibratoMS1.Value;
            app.LampEfecto1.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
        end

        % Value changed function: DistCheckBox1
        function DistCheckBox1ValueChanged(app, event)
            global distFlag1;
            distFlag1 = app.DistCheckBox1.Value;
            app.LampEfecto1.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
        end

        % Value changed function: EcoCheckBox1
        function EcoCheckBox1ValueChanged(app, event)
            global ecoFlag1;
            ecoFlag1 = app.EcoCheckBox1.Value;
            app.LampEfecto1.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
        end

        % Value changed function: EcoDelayEditField1
        function EcoDelayEditField1ValueChanged(app, event)
            global ecoDelay1;
            ecoDelay1 = app.EcoDelayEditField1.Value;
            app.LampEfecto1.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
            
        end

        % Value changed function: EcoAlfaEditField1
        function EcoAlfaEditField1ValueChanged(app, event)
            global ecoAlfa1;
            ecoAlfa1 = app.EcoAlfaEditField1.Value;
            app.LampEfecto1.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
            
        end

        % Value changed function: DistValueEditField1
        function DistValueEditField1ValueChanged(app, event)
            global distValue1;
            distValue1 = app.DistValueEditField1.Value;
            app.LampEfecto1.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
        end

        % Value changed function: EcoCheckBox2
        function EcoCheckBox2ValueChanged(app, event)
            global ecoFlag2;
            ecoFlag2 = app.EcoCheckBox2.Value;
            app.LampEfecto2.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
            
        end

        % Value changed function: EcoAlfaEditField2
        function EcoAlfaEditField2ValueChanged(app, event)
            global ecoAlfa2;
            ecoAlfa2 = app.EcoAlfaEditField2.Value;
            app.LampEfecto2.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
            
        end

        % Value changed function: EcoDelayEditField2
        function EcoDelayEditField2ValueChanged(app, event)
            global ecoDelay2;
            ecoDelay2 = app.EcoDelayEditField2.Value;
            app.LampEfecto2.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
            
        end

        % Value changed function: TremoloCheckBox2
        function TremoloCheckBox2ValueChanged(app, event)
            global tremoloFlag2;
            tremoloFlag2 = app.TremoloCheckBox2.Value;
            app.LampEfecto2.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
            
        end

        % Value changed function: FlangerCheckBox2
        function FlangerCheckBox2ValueChanged(app, event)
            global flangerFlag2;
            flangerFlag2 = app.FlangerCheckBox2.Value;
            app.LampEfecto2.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
            
        end

        % Value changed function: VibratoCheckBox2
        function VibratoCheckBox2ValueChanged(app, event)
            global vibratoFlag2;
            vibratoFlag2 = app.VibratoCheckBox2.Value;
            app.LampEfecto2.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
            
        end

        % Value changed function: DistCheckBox2
        function DistCheckBox2ValueChanged(app, event)
            global distFlag2;
            distFlag2 = app.DistCheckBox2.Value;
            app.LampEfecto2.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
            
        end

        % Callback function
        function SliderTremolo2ValueChanged(app, event)
            global tremoloValue2;
            tremoloValue2 = app.SliderTremolo2.Value;
            app.LampEfecto2.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
            
        end

        % Value changed function: SliderFlanger2
        function SliderFlanger2ValueChanged(app, event)
            global flangerValue2;
            flangerValue2 = app.SliderFlanger2.Value;
            app.LampEfecto2.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
            
        end

        % Value changed function: SliderVibratoFreq2
        function SliderVibratoFreq2ValueChanged(app, event)
            global vibratoFreqValue2;
            vibratoFreqValue2 = app.SliderVibratoFreq2.Value;
            app.LampEfecto2.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
            
        end

        % Value changed function: SliderVibratoMS2
        function SliderVibratoMS2ValueChanged(app, event)
            global vibratoMSValue2;
            vibratoMSValue2= app.SliderVibratoMS2.Value;
            app.LampEfecto2.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
            
        end

        % Value changed function: DistValueEditField2
        function DistValueEditField2ValueChanged(app, event)
            global distValue2;
            distValue2 = app.DistValueEditField2.Value;
            app.LampEfecto2.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
            
        end

        % Button pushed function: AplicarEfecto2
        function AplicarEfecto2ButtonPushed(app, event)
            global band2Data;
            global Fs;
            global effectSong2;
            global ecoFlag2;
            global ecoAlfa2;
            global ecoDelay2;
            global tremoloFlag2;
            global tremoloValue2;
            global flangerFlag2;
            global flangerValue2;
            global vibratoFlag2;
            global vibratoFreqValue2;
            global vibratoMSValue2;
            global distFlag2;
            global distValue2;
            global channels;
            global convData2;
            
            global effect1Active;
            global effect2Active;
            
            %4186 Hz Frecuencia mas alta de un piano
            
            effectSong2 = band2Data;
            
            % Aplicar Eco
            if (ecoFlag2 == 1)
                if (channels == 2)
                    effectSong2(:,1) = app.eco(effectSong2(:,1), ecoAlfa2, ecoDelay2, Fs);
                    effectSong2(:,2) = app.eco(effectSong2(:,2), ecoAlfa2, ecoDelay2, Fs);
                else
                    effectSong2 = app.eco(effectSong2, ecoAlfa2, ecoDelay2, Fs);
                end
            end
            
            % Aplicar tremolo
            if (tremoloFlag2 == 1)
                if (channels == 2)
                    effectSong2(:,1) = app.tremolo(effectSong2(:,1), convData2);
                    effectSong2(:,2) = app.tremolo(effectSong2(:,2), convData2);
                else
                    effectSong2 = app.tremolo(effectSong2, convData2);
                end
            end
            
            % aplicar Flanger
            if (flangerFlag2 == 1)
                if (channels == 2)
                    effectSong2(:,1) = app.flanger(effectSong2(:,1), flangerValue2, Fs);
                    effectSong2(:,2) = app.flanger(effectSong2(:,2), flangerValue2, Fs);
                else
                    effectSong2 = app.flanger(effectSong2, flangerValue2, Fs);
                end
            end
            
            % aplicar vibrato
            if (vibratoFlag2 == 1)
                if (channels == 2)
                    effectSong2(:,1) = app.vibrato(effectSong2(:,1), vibratoFreqValue2, vibratoMSValue2, Fs);
                    effectSong2(:,2) = app.vibrato(effectSong2(:,2), vibratoFreqValue2, vibratoMSValue2, Fs);
                else
                    effectSong2 = app.vibrato(effectSong2, vibratoFreqValue2, vibratoMSValue2, Fs);
                end
            end
            
            % aplicar Efecto Ring
            if (distFlag2 == 1)
                if (channels == 2)
                    effectSong2(:,1) = app.ring(effectSong2(:,1), distValue2, Fs);
                    effectSong2(:,2) = app.ring(effectSong2(:,2), distValue2, Fs);
                else
                    effectSong2 = app.ring(effectSong2, distValue2, Fs);
                end
            end
            app.LampEfecto2.Color = [0.47 0.67 0.19];
            
            effect2Active = true;
            
            if (effect2Active & effect1Active)
                app.AplicarGanancia.Enable = true;
                app.ReproducirGanancia.Enable = true;
            end
        end

        % Button pushed function: ReproducirEfecto2
        function ReproducirEfecto2ButtonPushed(app, event)
            global effectSong2;
            global Fs;
            global fileSelected;
            if isequal(fileSelected, 1)
                soundsc(effectSong2, Fs);
            end
        end

        % Value changed function: GananciadefiltrosSlider
        function GananciadefiltrosSliderValueChanged(app, event)
            global ganancia;
            ganancia = app.GananciadefiltrosSlider.Value;
            app.LampGanancia.Color = [0.64 0.08 0.18];
        end

        % Button pushed function: AplicarGanancia
        function AplicarGananciaButtonPushed(app, event)
            global ganancia;
            global finalData;
            global effectSong1;
            global effectSong2;
            global channels;
            global Fs;
            
            if (channels == 2)
                finalData(:,1) = ganancia*effectSong2(:,1) + (1-ganancia)*effectSong1(:,1);
                finalData(:,2) = ganancia*effectSong2(:,2) + (1-ganancia)*effectSong1(:,2);
            else
                finalData = ganancia*effectSong2 + (1-ganancia)*effectSong1;
            end
            app.plotSpectrum(app.EspectroSalidaAxes, finalData, Fs);
            app.LampGanancia.Color = [0.47 0.67 0.19];
            
            % habilitar boton para guardar audio editado
            app.GuardarButton.Enable = true;
            app.GenerarmatButton.Enable = true;
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            global outputPath;
            global outputFile;
            global Fs;
            global finalData;
            
            [outputFile, outputPath] = uiputfile(".wav");
            if isequal(outputFile,0)
                % not file
                app.ArchivoAudioLabel_2.Text = "<Archivo Audio>";
            else
                app.ArchivoAudioLabel_2.Text = outputFile;
                audiowrite(strcat(outputPath, outputFile), finalData, Fs);
            end
            
        end

        % Button pushed function: ReproducirGanancia
        function ReproducirGananciaButtonPushed(app, event)
            global finalData;
            global Fs;
            
            soundsc(finalData, Fs);
        end

        % Button pushed function: GenerarmatButton
        function GenerarmatButtonPushed(app, event)
            global ganancia;
            global songData;
            global band1Data;
            global band2Data;
            global effectSong1;
            global effectSong2;
            global finalData;
            global channels;
            global Fs;
            
            [outputFile, outputPath] = uiputfile(".mat");
            if isequal(outputFile,0)
                % not file
            else
                name = strcat(outputPath, outputFile);
                save(name, "songData", "Fs", "channels", "band1Data", "band2Data", "effectSong1", "effectSong2", "finalData", "ganancia");
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            global convData;
            convData = app.DropDown.Value;
            app.LampEfecto1.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            global convData2;
            convData2 = app.DropDown_2.Value;
            app.LampEfecto2.Color = [0.64 0.08 0.18];
            app.LampGanancia.Color = [0.64 0.08 0.18];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UnidaddeEfectosdeAudioUIFigure = uifigure('Visible', 'off');
            app.UnidaddeEfectosdeAudioUIFigure.Name = 'Unidad de Efectos de Audio';
            app.Efectos1Panel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.Efectos1Panel.Title = 'Efectos 1';
            app.EcoCheckBox1 = uicheckbox(app.Efectos1Panel);
            app.EcoCheckBox1.ValueChangedFcn = createCallbackFcn(app, @EcoCheckBox1ValueChanged, true);
            app.EcoCheckBox1.Text = 'Eco';
            app.TremoloCheckBox1 = uicheckbox(app.Efectos1Panel);
            app.TremoloCheckBox1.ValueChangedFcn = createCallbackFcn(app, @TremoloCheckBox1ValueChanged, true);
            app.TremoloCheckBox1.Text = 'Reverb.';
            app.FlangerCheckBox1 = uicheckbox(app.Efectos1Panel);
            app.FlangerCheckBox1.ValueChangedFcn = createCallbackFcn(app, @FlangerCheckBox1ValueChanged, true);
            app.FlangerCheckBox1.Text = 'Flanger';
            app.DistCheckBox1 = uicheckbox(app.Efectos1Panel);
            app.DistCheckBox1.ValueChangedFcn = createCallbackFcn(app, @DistCheckBox1ValueChanged, true);
            app.DistCheckBox1.Text = 'Dist.';
            app.VibratoCheckBox1 = uicheckbox(app.Efectos1Panel);
            app.VibratoCheckBox1.ValueChangedFcn = createCallbackFcn(app, @VibratoCheckBox1ValueChanged, true);
            app.VibratoCheckBox1.Text = 'Vibrato';
            app.AplicarEfecto1 = uibutton(app.Efectos1Panel, 'push');
            app.AplicarEfecto1.ButtonPushedFcn = createCallbackFcn(app, @AplicarEfecto1ButtonPushed, true);
            app.AplicarEfecto1.Enable = 'off';
            app.AplicarEfecto1.Text = 'Aplicar';
            app.ReproducirEfecto1 = uibutton(app.Efectos1Panel, 'push');
            app.ReproducirEfecto1.ButtonPushedFcn = createCallbackFcn(app, @ReproducirEfecto1ButtonPushed, true);
            app.ReproducirEfecto1.Enable = 'off';
            app.ReproducirEfecto1.Text = 'Reproducir';
            app.LampEfecto1 = uilamp(app.Efectos1Panel);
            app.SLabel = uilabel(app.Efectos1Panel);
            app.SLabel.Text = 'S';
            app.SliderFlanger1 = uislider(app.Efectos1Panel);
            app.SliderFlanger1.Limits = [0.5 5];
            app.SliderFlanger1.ValueChangedFcn = createCallbackFcn(app, @SliderFlanger1ValueChanged, true);
            app.SliderFlanger1.Value = 0.5;
            app.SliderVibratoFreq1 = uislider(app.Efectos1Panel);
            app.SliderVibratoFreq1.Limits = [1 15];
            app.SliderVibratoFreq1.ValueChangedFcn = createCallbackFcn(app, @SliderVibratoFreq1ValueChanged, true);
            app.SliderVibratoFreq1.Value = 1;
            app.HzLabel_6 = uilabel(app.Efectos1Panel);
            app.HzLabel_6.Text = 'Hz';
            app.HzLabel_7 = uilabel(app.Efectos1Panel);
            app.HzLabel_7.Text = 'Hz';
            app.SliderVibratoMS1 = uislider(app.Efectos1Panel);
            app.SliderVibratoMS1.Limits = [0.5 5];
            app.SliderVibratoMS1.ValueChangedFcn = createCallbackFcn(app, @SliderVibratoMS1ValueChanged, true);
            app.SliderVibratoMS1.Value = 0.5;
            app.msLabel = uilabel(app.Efectos1Panel);
            app.msLabel.Text = 'ms';
            app.DelayEditFieldLabel = uilabel(app.Efectos1Panel);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.EcoDelayEditField1 = uieditfield(app.Efectos1Panel, 'numeric');
            app.EcoDelayEditField1.Limits = [0 10];
            app.EcoDelayEditField1.ValueChangedFcn = createCallbackFcn(app, @EcoDelayEditField1ValueChanged, true);
            app.AlfaEditFieldLabel = uilabel(app.Efectos1Panel);
            app.AlfaEditFieldLabel.Text = 'Alfa';
            app.EcoAlfaEditField1 = uieditfield(app.Efectos1Panel, 'numeric');
            app.EcoAlfaEditField1.Limits = [0 10];
            app.EcoAlfaEditField1.ValueChangedFcn = createCallbackFcn(app, @EcoAlfaEditField1ValueChanged, true);
            app.HzLabel_9 = uilabel(app.Efectos1Panel);
            app.HzLabel_9.Text = 'Hz';
            app.HzLabel_10 = uilabel(app.Efectos1Panel);
            app.HzLabel_10.Text = 'Hz';
            app.FreqLabel = uilabel(app.Efectos1Panel);
            app.FreqLabel.Text = 'Freq';
            app.DistValueEditField1 = uieditfield(app.Efectos1Panel, 'numeric');
            app.DistValueEditField1.Limits = [20 6000];
            app.DistValueEditField1.ValueChangedFcn = createCallbackFcn(app, @DistValueEditField1ValueChanged, true);
            app.DistValueEditField1.Value = 20;
            app.DropDown = uidropdown(app.Efectos1Panel);
            app.DropDown.Items = {'Catedral', 'Iglesia', 'Envase', 'Piramide', 'Habitacion', 'Sanitario'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Catedral';
            app.ArchivoEntradaPanel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.ArchivoEntradaPanel.Title = 'Archivo Entrada';
            app.ExaminarButton_2 = uibutton(app.ArchivoEntradaPanel, 'push');
            app.ExaminarButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExaminarButton_2Pushed, true);
            app.ExaminarButton_2.Text = 'Examinar';
            app.ReproducirarchivoButton = uibutton(app.ArchivoEntradaPanel, 'push');
            app.ReproducirarchivoButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirarchivoButtonPushed, true);
            app.ReproducirarchivoButton.Enable = 'off';
            app.ReproducirarchivoButton.Text = 'Reproducir archivo';
            app.ArchivoAudioLabel = uilabel(app.ArchivoEntradaPanel);
            app.ArchivoAudioLabel.Text = '<Archivo Audio>';
            app.Panel_2 = uipanel(app.ArchivoEntradaPanel);
            app.DuracionLabel = uilabel(app.Panel_2);
            app.DuracionLabel.Text = 'Duracion';
            app.Panel_3 = uipanel(app.Panel_2);
            app.DurationLabel = uilabel(app.Panel_3);
            app.DurationLabel.Text = '00:00';
            app.CanalesLabel = uilabel(app.Panel_2);
            app.CanalesLabel.Text = 'Canales';
            app.Panel_4 = uipanel(app.Panel_2);
            app.NumCanalesLabel = uilabel(app.Panel_4);
            app.NumCanalesLabel.Text = '-';
            app.Banda1Panel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.Banda1Panel.Title = 'Banda 1';
            app.FreqaltaEditFieldLabel = uilabel(app.Banda1Panel);
            app.FreqaltaEditFieldLabel.Text = 'Freq. alta';
            app.FreqaltaEditField = uieditfield(app.Banda1Panel, 'numeric');
            app.FreqaltaEditField.Limits = [0 20000];
            app.FreqaltaEditField.ValueDisplayFormat = '%.0f';
            app.FreqaltaEditField.ValueChangedFcn = createCallbackFcn(app, @FreqaltaEditFieldValueChanged, true);
            app.FreqaltaEditField.Value = 20000;
            app.FreqbajaEditFieldLabel = uilabel(app.Banda1Panel);
            app.FreqbajaEditFieldLabel.Text = 'Freq. baja';
            app.FreqbajaEditField = uieditfield(app.Banda1Panel, 'numeric');
            app.FreqbajaEditField.Limits = [0 20000];
            app.FreqbajaEditField.ValueDisplayFormat = '%.0f';
            app.FreqbajaEditField.ValueChangedFcn = createCallbackFcn(app, @FreqbajaEditFieldValueChanged, true);
            app.HzLabel = uilabel(app.Banda1Panel);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.Banda1Panel);
            app.HzLabel_2.Text = 'Hz';
            app.AplicarBanda1 = uibutton(app.Banda1Panel, 'push');
            app.AplicarBanda1.ButtonPushedFcn = createCallbackFcn(app, @AplicarBanda1ButtonPushed, true);
            app.AplicarBanda1.Enable = 'off';
            app.AplicarBanda1.Text = 'Aplicar';
            app.LampBanda1 = uilamp(app.Banda1Panel);
            app.GananciaPanel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.GananciaPanel.Title = 'Ganancia';
            app.GananciadefiltrosSlider = uislider(app.GananciaPanel);
            app.GananciadefiltrosSlider.Limits = [0 1];
            app.GananciadefiltrosSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.GananciadefiltrosSlider.ValueChangedFcn = createCallbackFcn(app, @GananciadefiltrosSliderValueChanged, true);
            app.GananciadefiltrosSlider.ValueChangingFcn = createCallbackFcn(app, @GananciadefiltrosSliderValueChanging, true);
            app.AplicarGanancia = uibutton(app.GananciaPanel, 'push');
            app.AplicarGanancia.ButtonPushedFcn = createCallbackFcn(app, @AplicarGananciaButtonPushed, true);
            app.AplicarGanancia.Enable = 'off';
            app.AplicarGanancia.Text = 'Aplicar';
            app.ReproducirGanancia = uibutton(app.GananciaPanel, 'push');
            app.ReproducirGanancia.ButtonPushedFcn = createCallbackFcn(app, @ReproducirGananciaButtonPushed, true);
            app.ReproducirGanancia.Enable = 'off';
            app.ReproducirGanancia.Text = 'Reproducir';
            app.LampGanancia = uilamp(app.GananciaPanel);
            app.Banda2Panel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.Banda2Panel.Title = 'Banda 2';
            app.FreqaltaEditField_2Label = uilabel(app.Banda2Panel);
            app.FreqaltaEditField_2Label.Text = 'Freq. alta';
            app.FreqaltaEditField_2 = uieditfield(app.Banda2Panel, 'numeric');
            app.FreqaltaEditField_2.Limits = [0 20000];
            app.FreqaltaEditField_2.ValueDisplayFormat = '%.0f';
            app.FreqaltaEditField_2.ValueChangedFcn = createCallbackFcn(app, @FreqaltaEditField_2ValueChanged, true);
            app.FreqaltaEditField_2.Value = 20000;
            app.FreqbajaEditField_2Label = uilabel(app.Banda2Panel);
            app.FreqbajaEditField_2Label.Text = 'Freq. baja';
            app.FreqbajaEditField_2 = uieditfield(app.Banda2Panel, 'numeric');
            app.FreqbajaEditField_2.Limits = [0 20000];
            app.FreqbajaEditField_2.ValueDisplayFormat = '%.0f';
            app.FreqbajaEditField_2.ValueChangedFcn = createCallbackFcn(app, @FreqbajaEditField_2ValueChanged, true);
            app.HzLabel_3 = uilabel(app.Banda2Panel);
            app.HzLabel_3.Text = 'Hz';
            app.HzLabel_4 = uilabel(app.Banda2Panel);
            app.HzLabel_4.Text = 'Hz';
            app.AplicarBanda2 = uibutton(app.Banda2Panel, 'push');
            app.AplicarBanda2.ButtonPushedFcn = createCallbackFcn(app, @AplicarBanda2ButtonPushed, true);
            app.AplicarBanda2.Enable = 'off';
            app.AplicarBanda2.Text = 'Aplicar';
            app.LampBanda2 = uilamp(app.Banda2Panel);
            app.EspectroArchivoEntradaPanel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.EspectroArchivoEntradaPanel.Title = 'Espectro Archivo Entrada';
            app.EspectroEntradaAxes = uiaxes(app.EspectroArchivoEntradaPanel);
            xlabel(app.EspectroEntradaAxes, 'Frecuencia (Hz)')
            ylabel(app.EspectroEntradaAxes, 'Magnitud (dB)')
            app.EspectroEfectoPanel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.EspectroEfectoPanel.Title = 'Espectro Efecto';
            app.EspectroEfectoAxes = uiaxes(app.EspectroEfectoPanel);
            xlabel(app.EspectroEfectoAxes, 'Frecuencia (Hz)')
            ylabel(app.EspectroEfectoAxes, 'Magnitud (dB)')
            app.EspectroArchivoSalidaPanel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.EspectroArchivoSalidaPanel.Title = 'Espectro Archivo Salida';
            app.EspectroSalidaAxes = uiaxes(app.EspectroArchivoSalidaPanel);
            xlabel(app.EspectroSalidaAxes, 'Frecuencia (Hz)')
            ylabel(app.EspectroSalidaAxes, 'Magnitud (dB)')
            app.ArchivosSalidaPanel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.ArchivosSalidaPanel.Title = 'Archivos Salida';
            app.GuardarButton = uibutton(app.ArchivosSalidaPanel, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Enable = 'off';
            app.GuardarButton.Text = 'Guardar';
            app.ArchivoAudioLabel_2 = uilabel(app.ArchivosSalidaPanel);
            app.ArchivoAudioLabel_2.Text = '<Archivo Audio>';
            app.GenerarmatButton = uibutton(app.ArchivosSalidaPanel, 'push');
            app.GenerarmatButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarmatButtonPushed, true);
            app.GenerarmatButton.Enable = 'off';
            app.GenerarmatButton.Text = 'Generar .mat';
            app.Efectos2Panel = uipanel(app.UnidaddeEfectosdeAudioUIFigure);
            app.Efectos2Panel.Title = 'Efectos 2';
            app.EcoCheckBox2 = uicheckbox(app.Efectos2Panel);
            app.EcoCheckBox2.ValueChangedFcn = createCallbackFcn(app, @EcoCheckBox2ValueChanged, true);
            app.EcoCheckBox2.Text = 'Eco';
            app.TremoloCheckBox2 = uicheckbox(app.Efectos2Panel);
            app.TremoloCheckBox2.ValueChangedFcn = createCallbackFcn(app, @TremoloCheckBox2ValueChanged, true);
            app.TremoloCheckBox2.Text = 'Reverb';
            app.FlangerCheckBox2 = uicheckbox(app.Efectos2Panel);
            app.FlangerCheckBox2.ValueChangedFcn = createCallbackFcn(app, @FlangerCheckBox2ValueChanged, true);
            app.FlangerCheckBox2.Text = 'Flanger';
            app.DistCheckBox2 = uicheckbox(app.Efectos2Panel);
            app.DistCheckBox2.ValueChangedFcn = createCallbackFcn(app, @DistCheckBox2ValueChanged, true);
            app.DistCheckBox2.Text = 'Dist.';
            app.VibratoCheckBox2 = uicheckbox(app.Efectos2Panel);
            app.VibratoCheckBox2.ValueChangedFcn = createCallbackFcn(app, @VibratoCheckBox2ValueChanged, true);
            app.VibratoCheckBox2.Text = 'Vibrato';
            app.AplicarEfecto2 = uibutton(app.Efectos2Panel, 'push');
            app.AplicarEfecto2.ButtonPushedFcn = createCallbackFcn(app, @AplicarEfecto2ButtonPushed, true);
            app.AplicarEfecto2.Enable = 'off';
            app.AplicarEfecto2.Text = 'Aplicar';
            app.ReproducirEfecto2 = uibutton(app.Efectos2Panel, 'push');
            app.ReproducirEfecto2.ButtonPushedFcn = createCallbackFcn(app, @ReproducirEfecto2ButtonPushed, true);
            app.ReproducirEfecto2.Enable = 'off';
            app.ReproducirEfecto2.Text = 'Reproducir';
            app.LampEfecto2 = uilamp(app.Efectos2Panel);
            app.SLabel_2 = uilabel(app.Efectos2Panel);
            app.SLabel_2.Text = 'S';
            app.SliderFlanger2 = uislider(app.Efectos2Panel);
            app.SliderFlanger2.Limits = [0.5 5];
            app.SliderFlanger2.ValueChangedFcn = createCallbackFcn(app, @SliderFlanger2ValueChanged, true);
            app.SliderFlanger2.Value = 0.5;
            app.SliderVibratoFreq2 = uislider(app.Efectos2Panel);
            app.SliderVibratoFreq2.Limits = [1 15];
            app.SliderVibratoFreq2.ValueChangedFcn = createCallbackFcn(app, @SliderVibratoFreq2ValueChanged, true);
            app.SliderVibratoFreq2.Value = 1;
            app.HzLabel_12 = uilabel(app.Efectos2Panel);
            app.HzLabel_12.Text = 'Hz';
            app.HzLabel_13 = uilabel(app.Efectos2Panel);
            app.HzLabel_13.Text = 'Hz';
            app.SliderVibratoMS2 = uislider(app.Efectos2Panel);
            app.SliderVibratoMS2.Limits = [0.5 10];
            app.SliderVibratoMS2.ValueChangedFcn = createCallbackFcn(app, @SliderVibratoMS2ValueChanged, true);
            app.SliderVibratoMS2.Value = 0.5;
            app.msLabel_2 = uilabel(app.Efectos2Panel);
            app.msLabel_2.Text = 'ms';
            app.DelayEditFieldLabel_2 = uilabel(app.Efectos2Panel);
            app.DelayEditFieldLabel_2.Text = 'Delay';
            app.EcoDelayEditField2 = uieditfield(app.Efectos2Panel, 'numeric');
            app.EcoDelayEditField2.Limits = [0 10];
            app.EcoDelayEditField2.ValueChangedFcn = createCallbackFcn(app, @EcoDelayEditField2ValueChanged, true);
            app.AlfaEditFieldLabel_2 = uilabel(app.Efectos2Panel);
            app.AlfaEditFieldLabel_2.Text = 'Alfa';
            app.EcoAlfaEditField2 = uieditfield(app.Efectos2Panel, 'numeric');
            app.EcoAlfaEditField2.Limits = [0 10];
            app.EcoAlfaEditField2.ValueChangedFcn = createCallbackFcn(app, @EcoAlfaEditField2ValueChanged, true);
            app.HzLabel_14 = uilabel(app.Efectos2Panel);
            app.HzLabel_14.Text = 'Hz';
            app.HzLabel_15 = uilabel(app.Efectos2Panel);
            app.HzLabel_15.Text = 'Hz';
            app.FreqLabel_2 = uilabel(app.Efectos2Panel);
            app.FreqLabel_2.Text = 'Freq';
            app.DistValueEditField2 = uieditfield(app.Efectos2Panel, 'numeric');
            app.DistValueEditField2.Limits = [20 6000];
            app.DistValueEditField2.ValueChangedFcn = createCallbackFcn(app, @DistValueEditField2ValueChanged, true);
            app.DistValueEditField2.Value = 20;
            app.DropDown_2 = uidropdown(app.Efectos2Panel);
            app.DropDown_2.Items = {'Catedral', 'Iglesia', 'Envase', 'Piramide', 'Habitacion', 'Sanitario'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'Catedral';
            % Show the figure after all components are created
            app.UnidaddeEfectosdeAudioUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProyectoApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UnidaddeEfectosdeAudioUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UnidaddeEfectosdeAudioUIFigure)
        end
    end
end